classdef FluorescenceProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OptionsMenu                     matlab.ui.container.Menu
        LoadFilesMenu                   matlab.ui.container.Menu
        PrintExcel                      matlab.ui.container.Menu
        ExportHeatMapMenu               matlab.ui.container.Menu
        ExportSingleHeatMap             matlab.ui.container.Menu
        ExportAllHeatMaps               matlab.ui.container.Menu
        FlimFitButton                   matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FigureTitle                     matlab.ui.control.EditField
        FigureTitleLabel                matlab.ui.control.Label
        ShowFitOptionsCheckBox          matlab.ui.control.CheckBox
        CropImagesCheckBox              matlab.ui.control.CheckBox
        YCrop                           RangeInput
        ApplyFitAllButton               matlab.ui.control.Button
        ApplyFitButton                  matlab.ui.control.Button
        BiFitTable                      matlab.ui.control.Table
        TriFitTable                     matlab.ui.control.Table
        SpectralBandsSpinner            matlab.ui.control.Spinner
        SpectralBandsSpinnerLabel       matlab.ui.control.Label
        ShowSpectralBandsCheckBox       matlab.ui.control.CheckBox
        SmoothingSpinnerLabel           matlab.ui.control.Label
        NormalisationPointSpinner       matlab.ui.control.Spinner
        ZeroPointSpinner                matlab.ui.control.Spinner
        ZeroPointSpinnerLabel           matlab.ui.control.Label
        NormalisationPointSpinnerLabel  matlab.ui.control.Label
        SmoothingSpinner                matlab.ui.control.Spinner
        ReprocessButton                 matlab.ui.control.Button
        LoadedFilesLabel                matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BiFitParameters                 matlab.ui.control.Table
        TriFitParameters                matlab.ui.control.Table
        MainDisplay                     matlab.ui.control.UIAxes
        Subplot1                        matlab.ui.control.UIAxes
        Subplot2                        matlab.ui.control.UIAxes
        Subplot3                        matlab.ui.control.UIAxes
        Subplot4                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        NormCheckBox                    matlab.ui.control.CheckBox
        CropCheckBox                    matlab.ui.control.CheckBox
        XCrop                           RangeInput
        delayCrop                       RangeInput
        CurrentBand                     matlab.ui.control.Label
        ContextLabel                    matlab.ui.control.Label
        DisplayContext                  matlab.ui.control.Button
        Context2                        matlab.ui.control.Button
        ColorScale                      RangeInput
        DisplayType                     matlab.ui.container.ButtonGroup
        HeatMapButton                   matlab.ui.control.ToggleButton
        FittingButton                   matlab.ui.control.ToggleButton
        CWButton                        matlab.ui.control.ToggleButton
        SpectralBand                    matlab.ui.control.Slider
        SpectralBandLabel               matlab.ui.control.Label
        IndexSlider                     matlab.ui.control.Slider
        IndexSliderLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        bin8 = load("Efficiency_bin8_2018.mat"); % Efficiency and Wavelengths of detector at binning 8
        WL_sel = [56 97 97 138 138 260] % wavelength selection
        Bands_sel = [400 451 451 500 500 651] % spectral bands based on WL_Sel
        FileName; PathName % Path of selected files
        delays_name % Delay file names for each LT measurement
        Temperature % List of temperatures for each LT measurement
        filter % Type of loaded files
        images % List of images for CW measurements
        decays % Decay data for each temperature
        longestDelay % index of decay with longest delay times
        sizeIndex % Index of largest delay in decays
        maxIndex % Index of largest intensity value in decays
        smoothing = 3 % smoothing value for CW spectra
        normalisation_peak = 3 % selected data point for normalisation of LT data
        single = 0 % logical check for single or all CW images showing
        rel_color = 1 % logical check for heatmap color scale switching between relative/absolute
        colorBarLims = [0 10] % manual limits for color bar for heatmap
        norm = 1 % logical check for CW display being normalised
        show_parameters = 0 % logical check for showing Fitting Parameters
        ZERO = 43 % zero point time delay of instrument
        pool % pool for parallel processing
        DynamicSpectralBands % specifies number of spectral bands to look at
        xLim = [0 0] % wavelength axes limits for heat map
        yLim = [0 0] % time axes limits for heat map
        tri_Opts % Fitting options for tri-exponential fitting
        bi_Opts % Fitting options for bi-exponential fitting
        imageYs % min/max indexes of the different images in each delay.
    end
    
    methods (Access = private)
        function image_list = processCW(app, FileName, PathName)
            image_list=cell(size(FileName,2),1);
            for i = 1:size(FileName,2)
                entireName=fullfile(PathName,FileName{i});
                image=Image.loadimh(entireName);
                image.efficiency(app.bin8.Efficiency_bin8');
                image_list{i} = image;
            end
        end

        % Processes the LT data using the loaded files
        % Loops through loaded files and processes the data according to
        % the data binning size.
        function decay_list = processLT(app, FileName, PathName)
            decay_list=cell(size(FileName,2),1);
            decayEnds=zeros(size(FileName,2),1);
            for k=1:size(FileName,2)
                NameDelays=num2str(app.delays_name{k},'%05.f');
                % Changes delays that are negative into the proper file
                % names (i.e. -100 -> 0100u)
                negIndex = find(app.delays_name{k}<0);
                if ~isempty(negIndex)
                    for i=negIndex(1):negIndex(end)
                        NameDelays(i,:) = [NameDelays(i,2:5) 'u'];
                    end
                end
                decay=Decay();
                % Adds each delay image into the decay and splits the
                % spectrum by bands
                for d=1:size(app.delays_name{k},1)
                    NameFileWithDelays=[FileName{k}(1:end-4),NameDelays(d,:),'.imh'];
                    entireName=fullfile(PathName,NameFileWithDelays);
                    image=Image.loadimh(entireName);
                    image.efficiency(app.bin8.Efficiency_bin8');
                    image.addBands(app.WL_sel);
                    decay.addImage(image);
                end
                decay.normalise(app.normalisation_peak);
                decay.gate=image.gate;
                decay_list{k}=decay;
                decayEnds(k)=decay.delays(end);
            end
            sizes=zeros(size(decay_list,1),1);
            for k=1:size(decay_list,1)
                sizes(k)=max(decay_list{k}.spectra, [], 'all');
            end
            app.maxIndex=find(sizes==max(sizes),1,'first');
            [~,app.longestDelay]=max(decayEnds);
            maxDelay = decay_list{app.longestDelay}.delays(end);
            app.yLim=[decay.delays(1) maxDelay];
            app.delayCrop.Lim1=decay.delays(1) ; app.delayCrop.Lim2=maxDelay;
        end

        % Creates list of temperatures from files
        function temperature_list = thermometer(~, FileName)
            temperature_list=strings(size(FileName,2),1);
            for k=1:size(FileName,2)
                CurrentFile=FileName{k};
                if contains(CurrentFile,'RT_')||contains(CurrentFile,'roomT')
                    temperature_list{k}='25';
                else
                    TempExtract=CurrentFile(strfind(CurrentFile,'_T')+1:strfind(CurrentFile,'_T')+4);
                    temperature_list{k}=TempExtract;
                    if contains(TempExtract,'_')==true
                        temperature_list{k}=TempExtract(1:strfind(TempExtract,'_')-1);
                    end
                    if contains(CurrentFile,'cool')
                        temperature_list{k}=append(temperature_list{k},'_cool');
                    end
                    if contains(CurrentFile,'t60')
                        temperature_list{k}=append(temperature_list{k},'_t60');
                    end
                    if contains(CurrentFile,'day2')||contains(CurrentFile,'24h')||contains(CurrentFile,'60h')
                        temperature_list{k}=append(temperature_list{k},'_day2');
                    end
                    if contains(CurrentFile,'_m_')
                        temperature_list{k}=append(temperature_list{k},'_m');
                    end
                    if contains(CurrentFile,'dark')
                        temperature_list{k}=append(temperature_list{k},'_dark');
                    end
                    if contains(CurrentFile,'LT2')||contains(CurrentFile,'LTus2')
                        temperature_list{k}=append(temperature_list{k},'_2');
                    end
                end
            end            
        end
        
        % Fits data with a tri-exponential function and bi-exponential
        % function
        function fitted_decays = triBiFit(app, decays, zero_point)
            parfor j=1:size(decays,1)
                [tri_fit{j}, tri_gof{j}, tri_curve{j}, tri_opts{j}] = Decay.createTriFit(decays{j}, zero_point);
                [bi_fit{j}, bi_gof{j}, bi_curve{j}, bi_opts{j}] = Decay.createBiFit(decays{j}, zero_point);
            end
            app.tri_Opts = tri_opts; app.bi_Opts = bi_opts;
            decays = Decay.applyTriFit(decays, tri_fit, tri_gof, tri_curve);
            fitted_decays = Decay.applyBiFit(decays, bi_fit, bi_gof, bi_curve);
            if isempty(decays{1}.tri_fitopts.Lower)
                Decay.applyOpts(fitted_decays, tri_opts, bi_opts);
            end
        end

        function relativeDisplay(app, type)
            switch type
                case {0,2}
                    app.DisplayContext.Text="Absolute Scale";
                    app.ContextLabel.Text="Current Scale: Relative";
                    clim(app.MainDisplay, "auto");
                    app.rel_color=1;
                case {1,3}
                    app.DisplayContext.Text="Relative Scale";
                    app.ContextLabel.Text="Current Scale: Absolute";
                    switch app.NormCheckBox.Value
                        case false
                            clim(app.MainDisplay,[0 log(max(app.decays{app.maxIndex}.spectra,[],"all"))]);
                        case true
                            clim(app.MainDisplay, "auto");
                    end
                    app.rel_color=0;
            end
        end

        function imageLabel(~, fig)
            xlim(fig, [370 700]);
            ylabel(fig, 'Intensity');
            fig.YDir = 'normal';
        end

        function decayLabel(~, fig)
            ylabel(fig, 'Delay (ns)');
        end

        % Plots an image CW onto chosen figure
        function updateImage(app, fig, index, type)
            switch type
                case 0
                    spectrum=app.images{index}.spectrum_bin8;
                case 1
                    spectrum=app.images{index}.spectrum_norm;
            end
            plot(fig, app.bin8.WL_bin8, smooth(spectrum, app.smoothing));
            fig.Title.String=app.Temperature(index);
            ylim(fig, [0, max(spectrum)]);
            app.imageLabel(fig);
        end

        % Show all images loaded in the CW files
        function showAllImages(app, image_list, type)
            app.showHeatMap();
            colorbar(app.MainDisplay,"off");
            colors=jet(size(image_list,1));
            spectra=zeros(size(image_list,1), size(app.bin8.WL_bin8, 1));
            switch type
                case 0
                    for i=1:size(image_list,1)
                        spectra(i,:)=smooth(image_list{i}.spectrum_bin8, app.smoothing);
                    end
                case 1
                    for i=1:size(image_list,1)
                        spectra(i,:)=smooth(image_list{i}.spectrum_norm, app.smoothing);
                    end
            end
            plot(app.MainDisplay, app.bin8.WL_bin8, spectra);
            ylim(app.MainDisplay, [0, max(spectra,[],"all")])
            app.imageLabel(app.MainDisplay);
            colororder(app.MainDisplay, colors);
            legend(app.MainDisplay, app.Temperature, 'Interpreter', 'none');
            app.MainDisplay.Title.String="All";
            app.single = 0;
        end

        % Display all images from each delay in a decay
        function delayImages(app, index, type)
            app.showHeatMap();
            colorbar(app.MainDisplay,"off");
            set(findobj(app.MainDisplay.Children, 'type', 'image'), 'Visible', 'off');
            set(findobj(app.MainDisplay.Children, 'type', 'line'), 'Visible', 'on');
            % colors=jet(size(app.decays{index}.CW,1));
            spectra=zeros(size(app.decays{index}.CW,1), size(app.bin8.WL_bin8, 1));
            switch type
                case 0
                    for i = 1:size(app.decays{index}.CW,1)
                        spectra(i,:)=smooth(app.decays{index}.CW, app.smoothing);
                    end
                case 1
                    for i = 1:size(app.decays{index}.CW,1)
                        spectra(i,:)=smooth(app.decays{index}.CW, app.smoothing);
                    end
            end
            plot(app.MainDisplay, app.bin8.WL_bin8, spectra);
            ylim(app.MainDisplay, [0, max(spectra,[],"all")])
            app.imageLabel(app.MainDisplay);
            % colororder(app.MainDisplay, colors);
            % legend(app.MainDisplay, string(app.decays{index}.delays), 'Interpreter', 'none');
        end

        function updateHeatMap(app, index)
            X = app.bin8.WL_bin8;
            Y = app.decays{index}.delays;
            Yq = linspace(min(Y), max(Y), 400);
            switch app.NormCheckBox.Value
                case false
                    V = app.decays{index}.spectra;
                    Vq = interp2(X, Y, log(V), X, Yq,'nearest');
                case true
                    V = log(app.decays{index}.spectra./(app.decays{index}.spectra(app.normalisation_peak,:)));
                    Vq = interp2(X, Y, V, X, Yq,'nearest');
            end
            imagesc(app.MainDisplay, X, Yq, Vq);
            % imagesc(app.MainDisplay, app.bin8.WL_bin8, app.decays{index}.delays, log(app.decays{index}.spectra));
            switch app.CropCheckBox.Value
                case false
                    axis(app.MainDisplay,[app.bin8.WL_bin8(1) app.bin8.WL_bin8(end) app.decays{app.longestDelay}.delays(1) app.decays{app.longestDelay}.delays(end)]);
                case true
                    axis(app.MainDisplay, [app.xLim app.yLim]);
            end
            app.decayLabel(app.MainDisplay);
            app.MainDisplay.Title.String=app.Temperature(index);
        end

        % Plots the data selected by index and band onto the graphs
        function plotFits(app, index, band)
            j = index; s = band;
            yData=app.decays{j}.spectra_Bands_norm(:,s);
            xData=app.decays{j}.delays-app.ZERO;
            semilogy(app.Subplot1, xData, yData,'-*', xData, app.decays{j}.bi_curve(:,s));
            xlim(app.Subplot1, [0,max(xData)]); ylim(app.Subplot1, [min(yData)*0.5, max(yData)*1.1]);
            semilogy(app.Subplot2, xData, yData,'-*', xData, app.decays{j}.tri_curve(:,s));
            xlim(app.Subplot2, [0,max(xData)]); ylim(app.Subplot2, [min(yData)*0.5, max(yData)*1.1]);
            plot(app.Subplot3, xData, (yData-app.decays{j}.bi_curve(:,s)).*(1./yData),'-*');
            xlim(app.Subplot3, [0,max(xData)]);
            plot(app.Subplot4, xData, (yData-app.decays{j}.tri_curve(:,s)).*(1./yData),'-*');
            xlim(app.Subplot4, [0,max(xData)]);
        end
        
        % Turns the fitting plots off while switching the heatmap on
        function showHeatMap(app)
            app.Subplot1.Visible="off";
            set(findobj(app.Subplot1.Children, 'type', 'line'), 'Visible', 'off');
            app.Subplot2.Visible="off";
            set(findobj(app.Subplot2.Children, 'type', 'line'), 'Visible', 'off');
            app.Subplot3.Visible="off";
            set(findobj(app.Subplot3.Children, 'type', 'line'), 'Visible', 'off');
            app.Subplot4.Visible="off";
            set(findobj(app.Subplot4.Children, 'type', 'line'), 'Visible', 'off');
            app.MainDisplay.Visible="on";
            set(findobj(app.MainDisplay.Children, 'type', 'image'), 'Visible', 'on');
            set(findobj(app.MainDisplay.Children, 'type', 'line'), 'Visible', 'off');
            colorbar(app.MainDisplay,Visible="on");
            legend(app.MainDisplay,'off');
        end
        
        % Hides the heatmaps and shows the fitting plots
        function showFitting(app, index, band)
            app.MainDisplay.Visible="off";
            set(findobj(app.MainDisplay.Children, 'type', 'image'), 'Visible', 'off');
            set(findobj(app.MainDisplay.Children, 'type', 'line'), 'Visible', 'off');
            colorbar(app.MainDisplay,"off");
            legend(app.MainDisplay,'off');
            app.MainDisplay.Title.Visible="on";
            app.Subplot1.Visible="on";
            set(findobj(app.Subplot1.Children, 'type', 'line'), 'Visible', 'on');
            app.Subplot2.Visible="on";
            set(findobj(app.Subplot2.Children, 'type', 'line'), 'Visible', 'on');
            app.Subplot3.Visible="on";
            set(findobj(app.Subplot3.Children, 'type', 'line'), 'Visible', 'on');
            app.Subplot4.Visible="on";
            set(findobj(app.Subplot4.Children, 'type', 'line'), 'Visible', 'on');
            app.plotFits(index, band);
        end
        
        % Moves the fitting plots up or down to make way for data table
        % below
        function moveFittingPlots(app, type)
            switch type
                case 0
                    app.Subplot1.Layout.Row = [1 3];
                    app.Subplot1.Layout.Column = 1;
                    app.Subplot2.Layout.Row = [1 3];
                    app.Subplot2.Layout.Column = 2;
                    app.Subplot3.Layout.Row = [4 6];
                    app.Subplot3.Layout.Column = 1;
                    app.Subplot4.Layout.Row = [4 6];
                    app.Subplot4.Layout.Column = 2;
                case 1
                    app.Subplot1.Layout.Row = [1 2];
                    app.Subplot1.Layout.Column = 1;
                    app.Subplot2.Layout.Row = [1 2];
                    app.Subplot2.Layout.Column = 2;
                    app.Subplot3.Layout.Row = [3 4];
                    app.Subplot3.Layout.Column = 1;
                    app.Subplot4.Layout.Row = [3 4];
                    app.Subplot4.Layout.Column = 2;
            end
        end
        
        % Displays the fitting parameters onto the data table
        function fitTable(app, index, band)
            j = index; s = band;
            app.TriFitParameters.Data=[app.decays{j}.tri_fit{s}.A1, app.decays{j}.tri_fit{s}.A2, app.decays{j}.tri_fit{s}.A3, ...
                                      app.decays{j}.tri_fit{s}.tau1, app.decays{j}.tri_fit{s}.tau2, app.decays{j}.tri_fit{s}.tau3, app.decays{j}.tri_fit{s}.offset];
            app.BiFitParameters.Data=[app.decays{j}.bi_fit{s}.A1, app.decays{j}.bi_fit{s}.A2, app.decays{j}.bi_fit{s}.tau1, ...
                                     app.decays{j}.bi_fit{s}.tau2, app.decays{j}.bi_fit{s}.offset];
        end

        % Updates the heatmap or fitting plots to given index.
        function decaySliderChange(app, index)
            switch app.DisplayType.SelectedObject
                case app.HeatMapButton
                    app.updateHeatMap(index);
                    app.tableFitOpts(index);
                case app.FittingButton
                    app.plotFits(index, app.SpectralBand.Value);
                    app.tableFitOpts(index);
                    if app.show_parameters==1
                        app.fitTable(index, app.SpectralBand.Value);
                    end
                case app.CWButton
                    app.delayImages(index, app.norm);
            end
            app.MainDisplay.Title.String=app.Temperature(index);
            app.FigureTitle.Value=app.Temperature(index);
            app.YCrop.Lim1 = app.imageYs{index}(1); app.YCrop.Lim2 = app.imageYs{index}(2);
        end

        % Updates the fitting plot to the given band
        function decayBandChange(app, band)
            app.plotFits(app.IndexSlider.Value, band);
            if app.show_parameters==1
                app.fitTable(app.IndexSlider.Value, band);
            end
        end

        % Displays UI based on the type of files chosen
        function displayUI(app, type)
            switch type
                case 1
                    app.HeatMapButton.Visible="on";
                    app.FittingButton.Visible="on";
                    app.SpectralBand.Visible="on"; app.SpectralBandLabel.Visible="on"; app.CurrentBand.Visible="on";
                    app.ZeroPointSpinner.Visible="on";app.ZeroPointSpinnerLabel.Visible="on";
                    app.NormalisationPointSpinner.Visible="on";app.NormalisationPointSpinnerLabel.Visible="on";
                    app.DisplayContext.Text="Absolute Scale";
                    app.ContextLabel.Text="Current Scale: Relative";
                    app.Context2.Text="Manual Scale";
                    app.Context2.Visible="on";
                    app.CropCheckBox.Visible="on";
                    app.NormCheckBox.Visible="on";
                    app.ShowSpectralBandsCheckBox.Visible="on";
                    app.ShowFitOptionsCheckBox.Visible="on";
                    app.CropImagesCheckBox.Visible="on";
                case 2
                    app.HeatMapButton.Visible="off";
                    app.FittingButton.Visible="off";
                    app.ContextLabel.Text="Current Scale: Normalised";
                    app.Context2.Visible="on";
                    app.Context2.Text="Single";
            end
            app.SmoothingSpinner.Visible="on";app.SmoothingSpinnerLabel.Visible="on";
            app.ReprocessButton.Visible="on";
            app.DisplayType.Visible="on";
            app.DisplayContext.Visible="on";
            app.ContextLabel.Visible="on";
            app.FigureTitle.Visible="on"; app.FigureTitleLabel.Visible="on";
            if size(app.FileName,2)~=1
                app.IndexSlider.Visible="on"; app.IndexSliderLabel.Visible="on";
            end
        end

        % Hides all the UI again for resetting
        function resetUI(app)
            app.IndexSlider.Visible='off'; app.IndexSliderLabel.Visible='off';
            app.IndexSlider.Value=1;
            app.SpectralBand.Visible='off';app.SpectralBandLabel.Visible='off'; app.CurrentBand.Visible="off";
            app.DisplayType.Visible='off';
            app.DisplayContext.Visible='off';
            app.ContextLabel.Visible='off';
            app.Context2.Visible="off";
            app.CropCheckBox.Visible="off";
            app.NormCheckBox.Visible="off";
            app.ShowSpectralBandsCheckBox.Visible="off"; app.ShowSpectralBandsCheckBox.Value=0;
            app.ShowFitOptionsCheckBox.Visible="off"; app.ShowFitOptionsCheckBox.Value=0;
            app.ReprocessButton.Visible="off";
            app.ZeroPointSpinner.Visible="off";app.ZeroPointSpinnerLabel.Visible="off";
            app.SmoothingSpinner.Visible="off";app.SmoothingSpinnerLabel.Visible="off";
            app.NormalisationPointSpinner.Visible="off";app.NormalisationPointSpinnerLabel.Visible="off";
            app.FigureTitle.Visible="off"; app.FigureTitleLabel.Visible="off";
            app.CropImagesCheckBox.Visible="off"; app.YCrop.Visible="off";
            app.ApplyFitButton.Visible="off"; app.ApplyFitAllButton.Visible="off";
            app.TriFitParameters.Visible="off";
            app.BiFitParameters.Visible="off";
        end

        % Generates editable numeric inputs for spectral band ranges
        function dynamicBandBoxes(app, bandNum, range)
            initPos1 = app.DynamicSpectralBands{1,1}.Position;
            initPos2 = app.DynamicSpectralBands{1,2}.Position;
            initPos3 = app.DynamicSpectralBands{1,3}.Position;
            box1 = initPos1 - [0, (bandNum-1)*31, 0, 0];
            dash = initPos2 - [0, (bandNum-1)*31, 0, 0];
            box2 = initPos3 - [0, (bandNum-1)*31, 0, 0];
            app.DynamicSpectralBands{bandNum,1} = uieditfield(app.LeftPanel, 'numeric', 'Position', box1, Limits=[329 755], Value=range(1));
            app.DynamicSpectralBands{bandNum,2} = uilabel(app.LeftPanel,"Text", '-','FontSize', 18,'HorizontalAlignment', 'center','Position', dash);
            app.DynamicSpectralBands{bandNum,3} = uieditfield(app.LeftPanel, 'numeric', 'Position', box2, Limits=[329 755], Value=range(2));
        end

        % Changes the limits of the index slider
        function indexSliderLimits(app, range)
            app.IndexSlider.Limits=[1 range];
            app.IndexSlider.MajorTicks=1:1:range;
            app.IndexSlider.MajorTickLabels=string(1:1:range);
        end

        % Changes the limits of the spectral band slider
        function spectralBandLimits(app, range)
            app.SpectralBand.Limits=[1 range];
            app.SpectralBand.MajorTicks=1:1:range;
            app.SpectralBand.MajorTickLabels=string(1:1:range);            
        end

        % Copies current MainDisplay for exporting
        function copyFigure(app, targetFig)
            copyobj([app.MainDisplay.Colorbar, app.MainDisplay], targetFig);
            copyobj(app.MainDisplay.Title.String, targetFig)
            set(targetFig, 'CreateFcn', 'set(gcbo, "Visible","on")');
        end

        % Copies the Fit Opts from the selected decay onto the table
        function tableFitOpts(app, index)
            app.BiFitTable.Data(:,1) = app.bi_Opts{index}{1};
            app.BiFitTable.Data(:,2) = app.bi_Opts{index}{2};
            app.BiFitTable.Data(:,3) = app.bi_Opts{index}{3};
            app.TriFitTable.Data(:,1) = app.tri_Opts{index}{1};
            app.TriFitTable.Data(:,2) = app.tri_Opts{index}{2};
            app.TriFitTable.Data(:,3)= app.tri_Opts{index}{3};
        end

        function applyOpt(app, index)
            app.bi_Opts{index}{1}=app.BiFitTable.Data(:,1); app.decays{index}.bi_fitopts.Lower=app.BiFitTable.Data(:,1);
            app.bi_Opts{index}{2}=app.BiFitTable.Data(:,2); app.decays{index}.bi_fitopts.StartPoint=app.BiFitTable.Data(:,2);
            app.bi_Opts{index}{3}=app.BiFitTable.Data(:,3); app.decays{index}.bi_fitopts.Upper=app.BiFitTable.Data(:,3);
            app.tri_Opts{index}{1}=app.TriFitTable.Data(:,1); app.decays{index}.tri_fitopts.Lower=app.TriFitTable.Data(:,1);
            app.tri_Opts{index}{2}=app.TriFitTable.Data(:,2); app.decays{index}.tri_fitopts.StartPoint=app.TriFitTable.Data(:,2);
            app.tri_Opts{index}{3}=app.TriFitTable.Data(:,3); app.decays{index}.tri_fitopts.Upper=app.TriFitTable.Data(:,3);
        end

        function applyAllOpt(app)
            for n=1:size(app.decays,1)
                app.bi_Opts{n}{1}=app.BiFitTable.Data(:,1); app.decays{n}.bi_fitopts.Lower=app.BiFitTable.Data(:,1);
                app.bi_Opts{n}{2}=app.BiFitTable.Data(:,2); app.decays{n}.bi_fitopts.StartPoint=app.BiFitTable.Data(:,2);
                app.bi_Opts{n}{3}=app.BiFitTable.Data(:,3); app.decays{n}.bi_fitopts.Upper=app.BiFitTable.Data(:,3);
                app.tri_Opts{n}{1}=app.TriFitTable.Data(:,1); app.decays{n}.tri_fitopts.Lower=app.TriFitTable.Data(:,1);
                app.tri_Opts{n}{2}=app.TriFitTable.Data(:,2); app.decays{n}.tri_fitopts.StartPoint=app.TriFitTable.Data(:,2);
                app.tri_Opts{n}{3}=app.TriFitTable.Data(:,3); app.decays{n}.tri_fitopts.Upper=app.TriFitTable.Data(:,3);
            end
        end

        function cropDecay(app, index)
            app.decays{index}.yCrop(app.imageYs{index}(1), app.imageYs{index}(2), app.bin8.Efficiency_bin8');
            app.decays{index}.addBands(app.WL_sel);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Sliders Initialisation
            app.spectralBandLimits(size(app.WL_sel,2)/2);
            app.SpectralBand.MinorTicks=[];
            app.CurrentBand.Text = app.Bands_sel(1)+" nm - "+app.Bands_sel(2)+" nm";
            app.IndexSlider.MinorTicks=[];
            %% Screen Size Initialisation
            screenSize=get(groot,"ScreenSize");
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            pause(2.5);
            app.UIFigure.Position=[left bottom width height];
            %% Bands_sel initialisation
            pos1 = [25 198 39 22];
            pos2 = [70 198 25 23];
            pos3 = [103 198 39 22];
            app.DynamicSpectralBands{1,1} = uieditfield(app.LeftPanel, 'numeric', 'Position', pos1, Limits=[329 755], Value=app.Bands_sel(1));
            app.DynamicSpectralBands{1,2} = uilabel(app.LeftPanel,"Text", '-','FontSize', 18,'HorizontalAlignment', 'center','Position', pos2);
            app.DynamicSpectralBands{1,3} = uieditfield(app.LeftPanel, 'numeric', 'Position', pos3, Limits=[329 755], Value=app.Bands_sel(2));
            app.DynamicSpectralBands{1,1}.Visible='off';
            app.DynamicSpectralBands{1,2}.Visible='off';
            app.DynamicSpectralBands{1,3}.Visible='off';
            for n = 2:3
                app.dynamicBandBoxes(n, [app.Bands_sel(n*2-1) app.Bands_sel(n*2)]);
                app.DynamicSpectralBands{n,1}.Visible='off';
                app.DynamicSpectralBands{n,2}.Visible='off';
                app.DynamicSpectralBands{n,3}.Visible='off';
            end
            %% Misc Initialisation
            app.xLim = [app.bin8.WL_bin8(1) app.bin8.WL_bin8(end)];
            app.MainDisplay.Title.Interpreter="none";
            try
                app.pool = parpool("Processes");
            catch
                return
            end

        end

        % Menu selected function: LoadFilesMenu
        function LoadFilesMenuSelected(app, event)
            [Files, Path, type]= uigetfile({'*.lst','LT Files (*.lst)'; '*.imh','CW Files (*.imh)'}, 'Select Files', app.PathName, 'MultiSelect', 'on');
            % If no files selected, do nothing
            if type==0
                return
            end
            app.resetUI();
            app.PathName = Path;
            app.FileName = cellstr(Files);
            app.filter = type;
            PathIdx=strfind(app.PathName,'\');
            switch app.filter
                case 1
                    app.LoadedFilesLabel.Text = "Loaded Files: Lifetime "+app.PathName(PathIdx(end-2)+1:end-1);
                    [delays_matrix,app.delays_name]=deal(cell(size(app.FileName,2),1));
                    for i = 1:size(app.FileName,2)
                        delays = load(fullfile(app.PathName,app.FileName{i}));
                        [delays_matrix{i}, app.delays_name{i}] = deal(delays(1:end));
                        % % Replaces delays that say -0100 to the actual 100500ns delay used
                        % if ismember(-100,delays_matrix{i})
                        %     delays_matrix{i}(delays_matrix{i}==-100)=100500;
                        % end
                    end
                    sizes=cellfun(@max,delays_matrix);
                    app.sizeIndex=find(sizes==max(sizes),1,'first');
                    app.decays = app.processLT(app.FileName, app.PathName);
                    app.Temperature = app.thermometer(app.FileName);
                    for d = 1:size(app.decays,1)
                        app.imageYs{d}=app.decays{d}.indexes;
                    end
                    % Sets the slider limits to the number of decays measured.
                    % Then displays the heatmap.
                    if size(app.FileName, 2)~=1
                        app.indexSliderLimits(size(app.Temperature,1));
                    end
                    app.decays = app.triBiFit(app.decays, app.ZERO);
                    app.MainDisplay.Colormap=turbo;
                    app.updateHeatMap(1);
                    app.showHeatMap();
                    app.FigureTitle.Value=app.Temperature(1);
                    app.tableFitOpts(1);
                    app.DisplayType.SelectedObject=app.HeatMapButton;
                    app.displayUI(app.filter);
                    app.FlimFitButton.Visible="on";
                case 2
                    app.LoadedFilesLabel.Text = "Loaded Files: Continuous Wave "+app.PathName(PathIdx(end-2)+1:end-1);
                    app.images=app.processCW(app.FileName, app.PathName);
                    app.Temperature=app.thermometer(app.FileName);
                    if size(app.FileName,2)~=1
                        app.indexSliderLimits(size(app.FileName,2));
                    end
                    app.showAllImages(app.images, app.norm);
                    app.MainDisplay.Visible="on";
                    app.DisplayType.SelectedObject=app.CWButton;
                    app.displayUI(app.filter);
                    app.DisplayContext.Text="Raw";
                    app.Context2.Text="Single Images";
                % Loops through selected files for their different delays and
                % converts any delays with text in them into the actual
                % numerical delay. Processes the data and generates a
                % temperature legend then creates a heat map of the first file.                
            end
        end

        % Menu selected function: PrintExcel
        function PrintExcelMenuSelected(app, event)
            if isempty(app.PathName)
                return
            end
            PathIdx=strfind(app.PathName,'\');
            [savefile, savepath] = uiputfile('*.xlsx',append("Exp_Fits_",app.PathName(PathIdx(end-1)+1:end-1)));
            if savepath==0
                return
            end
            results=zeros(size(app.decays,1),21,size(app.decays{1}.spectra_Bands,2));
            for j=1:size(results,1)
                for s=1:size(results,3)
                    fit3 = app.decays{j}.tri_fit{s};
                    fit2 = app.decays{j}.bi_fit{s};
                    results(j,1,s)=fit2.A1;
                    results(j,2,s)=fit2.A2;
                    results(j,3,s)=fit2.tau1;
                    results(j,4,s)=fit2.tau2;
                    results(j,5,s)=fit2.offset;
                    results(j,6,s)=fit3.A1;
                    results(j,7,s)=fit3.A2;
                    results(j,8,s)=fit3.A3;
                    results(j,9,s)=fit3.tau1;
                    results(j,10,s)=fit3.tau2;
                    results(j,11,s)=fit3.tau3;
                    results(j,12,s)=fit3.offset;
                    results(j,13,s)=fit2.A1*100/(fit2.A1+fit2.A2);
                    results(j,14,s)=fit2.A2*100/(fit2.A1+fit2.A2);
                    results(j,15,s)=(fit2.A1*(fit2.tau1^2)+fit2.A2*(fit2.tau2^2))/...
                                    (fit2.A1*fit2.tau1+fit2.A2*fit2.tau2);
                    results(j,16,s)=app.decays{j}.bi_gof(s).adjrsquare;
                    results(j,17,s)=fit3.A1*100/(fit3.A1+fit3.A2+fit3.A3);
                    results(j,18,s)=fit3.A2*100/(fit3.A1+fit3.A2+fit3.A3);
                    results(j,19,s)=fit3.A3*100/(fit3.A1+fit3.A2+fit3.A3);
                    results(j,20,s)=(fit3.A1*(fit3.tau1^2)+fit3.A2*(fit3.tau2^2)+fit3.A3*(fit3.tau3^2))/...
                                    (fit3.A1*fit3.tau1+fit3.A2*fit3.tau2+fit3.A3*fit3.tau3);
                    results(j,21,s)=app.decays{j}.tri_gof(s).adjrsquare;
                end
            end
            variables={"","biexp_A1","biexp_A2","biexp_tau1","biexp_tau2","biexp_offset", ...
                       "triexp_A1","triexp_A2","triexp_A3","triexp_tau1","triexp_tau2","triexp_tau3", "triexp_offset",...
                       "biexp_A1%","biexp_A2%","biexp_tau_avg","biexp_adjrsquare",...
                       "triexp_A1%","triexp_A2%","triexp_A3%","triexp_tau_avg","triexp_adjrsquare"};
            % Appends fit options to the main results
            fitRow= {"Lower"; "Start"; "Upper"};
            fitRow = [cellstr(reshape([app.Temperature'; strings(size(app.Temperature',1)*2,size(app.Temperature',2))],[],1)) repmat(fitRow, size(app.Temperature))];
            biFitHead={"","","bi_A1","bi_A2","bi_offset","bi_tau1","bi_tau2"};
            triFitHead={"","","tri_A1","tri_A2","tri_A3","tri_offset","tri_tau1","tri_tau2","tri_tau3"};
            biFitData=zeros(size(app.Temperature,1)*3,5); triFitData=zeros(size(app.Temperature,1)*3,7);
            for j = 1:size(app.Temperature,1)
                biFitData(j*3-2,:)=app.bi_Opts{j}{1};
                biFitData(j*3-1,:)=app.bi_Opts{j}{2};
                biFitData(j*3,:)=app.bi_Opts{j}{3};
                triFitData(j*3-2,:)=app.tri_Opts{j}{1};
                triFitData(j*3-1,:)=app.tri_Opts{j}{2};
                triFitData(j*3,:)=app.tri_Opts{j}{3};
            end
            biRow = [biFitHead; [fitRow num2cell(biFitData)]];
            triRow = [triFitHead; [fitRow num2cell(triFitData)]];
            resultTable=cell(size(results,3),1);
            % Prints all results to excel
            for s=1:size(results,3)
                mainData = vertcat(variables, [cellstr(app.Temperature) num2cell(results(:,:,s))]);
                mainData2 = vertcat(mainData, repmat({""},1,size(mainData,2)), ...
                             [biRow repmat({""},size(biRow,1),1) triRow ...
                             cellstr(strings(size(biRow,1), size(mainData,2)-size(biRow,2)-size(triRow,2)-1))]);
                resultTable{s}= mainData2;
            end
            for i=1:size(resultTable,1)
                writecell(resultTable{i}, fullfile(savepath,savefile), 'Sheet',app.Bands_sel(i*2-1)+" nm - "+app.Bands_sel(i*2)+" nm");
            end
        end

        % Menu selected function: ExportAllHeatMaps
        function ExportAllHeatMapsMenuSelected(app, event)
            if isempty(app.PathName)
                return
            end
            defaultPath=[app.PathName app.FileName{1}];
            [saveName, savePath, filetype] = uiputfile({'*.fig','Matlab Fig'; '*.png','PNG'; '*.jpg','JPEG'; '*.tif','TIFF'},'', defaultPath(1:end-4));
            if savePath==0
                return
            end
            if filetype==1
                newFig=gobjects(size(app.FileName,2),1);
                for i=1:size(app.FileName,2)
                    newFig(i) = figure(Visible="off");
                    app.updateHeatMap(i);
                    app.copyFigure(newFig(i));
                end
                savefig(newFig, [savePath saveName]);
                close(newFig);
                app.updateHeatMap(app.IndexSlider.Value);
            else
                for i=1:size(app.FileName,2)
                    app.updateHeatMap(i);
                    saveIndex = [saveName(1:end-4) '_' num2str(i) saveName(end-3:end)];
                    exportgraphics(app.MainDisplay, [savePath saveIndex],"Resolution",300);
                end
                app.updateHeatMap(app.IndexSlider.Value);
            end
        end

        % Menu selected function: ExportSingleHeatMap
        function ExportSingleHeatMapMenuSelected(app, event)
            if isempty(app.PathName)
                return
            end
            index = app.IndexSlider.Value;
            defaultPath=[app.PathName app.FileName{index}];
            [saveName, savePath, filetype] = uiputfile({'*.fig','Matlab Fig'; '*.png','PNG'; '*.jpg','JPEG'; '*.tif','TIFF'},'', defaultPath(1:end-4));
            if savePath==0
                return
            end
            if filetype==1
                newFig = figure(Visible="off");
                app.copyFigure(newFig);
                savefig(newFig, [savePath saveName]);
                close(newFig);
            else
                exportgraphics(app.MainDisplay, [savePath saveName],"Resolution",300);
            end
        end

        % Menu selected function: FlimFitButton
        function FlimFitButtonMenuSelected(app, event)
            flim_fit_ns(1,app.PathName,app.FileName{app.IndexSlider.Value}(1:end-4));
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            app.Bands_sel = [];
            app.WL_sel = [];
            for b=1:size(app.DynamicSpectralBands,1)
                app.Bands_sel = [app.Bands_sel app.DynamicSpectralBands{b,1}.Value app.DynamicSpectralBands{b,3}.Value];
                [~,idx1]=min(abs(app.bin8.WL_bin8 - app.DynamicSpectralBands{b,1}.Value));
                [~,idx2]=min(abs(app.bin8.WL_bin8 - app.DynamicSpectralBands{b,3}.Value));
                app.WL_sel = [app.WL_sel idx1 idx2];
            end
            for i=1:size(app.decays,1)
                app.decays{i}.addBands(app.WL_sel);
            end
            for i=1:size(app.decays,1)
                app.decays{i}.normalise(app.normalisation_peak);
            end
            app.spectralBandLimits(size(app.WL_sel,2)/2);
            app.triBiFit(app.decays, app.ZERO);
            index=app.IndexSlider.Value;
            band=app.SpectralBand.Value;
            app.CurrentBand.Text = app.Bands_sel(band*2-1)+" nm - "+app.Bands_sel(band*2)+" nm";
            switch app.DisplayType.SelectedObject
                case app.FittingButton
                    showFitting(app, index, band);
                case app.HeatMapButton
                    app.showHeatMap();
            end
        end

        % Value changed function: ShowSpectralBandsCheckBox
        function ShowSpectralBandsCheckBoxValueChanged(app, event)
            value = app.ShowSpectralBandsCheckBox.Value;
            switch value
                case true
                    app.NormalisationPointSpinner.Visible="off"; app.NormalisationPointSpinnerLabel.Visible="off";
                    app.ZeroPointSpinner.Visible="off"; app.ZeroPointSpinnerLabel.Visible="off";
                    app.SmoothingSpinner.Visible="off"; app.SmoothingSpinnerLabel.Visible="off";
                    app.ShowFitOptionsCheckBox.Visible="off";
                    app.CropImagesCheckBox.Visible="off";
                    app.FigureTitle.Visible="off"; app.FigureTitleLabel.Visible="off";
                    app.SpectralBandsSpinner.Visible="on"; app.SpectralBandsSpinnerLabel.Visible="on";
                    for i=1:size(app.DynamicSpectralBands,1)
                        app.DynamicSpectralBands{i,1}.Visible="on";
                        app.DynamicSpectralBands{i,2}.Visible="on";
                        app.DynamicSpectralBands{i,3}.Visible="on";
                    end
                case false
                    app.NormalisationPointSpinner.Visible="on"; app.NormalisationPointSpinnerLabel.Visible="on";
                    app.ZeroPointSpinner.Visible="on"; app.ZeroPointSpinnerLabel.Visible="on";
                    app.SmoothingSpinner.Visible="on"; app.SmoothingSpinnerLabel.Visible="on";
                    app.ShowFitOptionsCheckBox.Visible="on";
                    app.CropImagesCheckBox.Visible="on";
                    app.FigureTitle.Visible="on"; app.FigureTitleLabel.Visible="on";
                    app.SpectralBandsSpinner.Visible="off"; app.SpectralBandsSpinnerLabel.Visible="off";
                    for i=1:size(app.DynamicSpectralBands,1)
                        app.DynamicSpectralBands{i,1}.Visible="off";
                        app.DynamicSpectralBands{i,2}.Visible="off";
                        app.DynamicSpectralBands{i,3}.Visible="off";
                    end
            end
        end

        % Value changed function: SpectralBandsSpinner
        function SpectralBandsSpinnerValueChanged(app, event)
            numBands = app.SpectralBandsSpinner.Value;
            % clears any existing band squares
            if numBands<size(app.DynamicSpectralBands,1)
                for i=numBands+1:size(app.DynamicSpectralBands,1)
                    for k=1:3
                    delete(app.DynamicSpectralBands{i,k});
                    end
                end
                app.DynamicSpectralBands = app.DynamicSpectralBands(1:numBands, :);
            else
                if (size(app.Bands_sel,2)/2)>=numBands
                    for n = size(app.DynamicSpectralBands,1)+1:numBands
                        app.dynamicBandBoxes(n, [app.Bands_sel(n*2-1) app.Bands_sel(n*2)]);
                    end
                else
                    for n = (size(app.DynamicSpectralBands,1))+1:(size(app.Bands_sel,2)/2)
                        app.dynamicBandBoxes(n, [app.Bands_sel(n*2-1) app.Bands_sel(n*2)]);
                    end
                    for n = (size(app.DynamicSpectralBands,1))+1:numBands
                        app.dynamicBandBoxes(n, [329 329]);
                    end
                end
            end
        end

        % Value changed function: ShowFitOptionsCheckBox
        function ShowFitOptionsCheckBoxValueChanged(app, event)
            value = app.ShowFitOptionsCheckBox.Value;
            switch value
                case true
                    app.NormalisationPointSpinner.Visible="off"; app.NormalisationPointSpinnerLabel.Visible="off";
                    app.ZeroPointSpinner.Visible="off"; app.ZeroPointSpinnerLabel.Visible="off";
                    app.SmoothingSpinner.Visible="off"; app.SmoothingSpinnerLabel.Visible="off";
                    app.ShowSpectralBandsCheckBox.Visible="off";
                    app.CropImagesCheckBox.Visible="off";
                    app.FigureTitle.Visible="off"; app.FigureTitleLabel.Visible="off";
                    app.ApplyFitButton.Visible="on";
                    app.ApplyFitAllButton.Visible="on";
                    app.BiFitTable.Visible="on";
                    app.TriFitTable.Visible="on";
                case false
                    app.NormalisationPointSpinner.Visible="on"; app.NormalisationPointSpinnerLabel.Visible="on";
                    app.ZeroPointSpinner.Visible="on"; app.ZeroPointSpinnerLabel.Visible="on";
                    app.SmoothingSpinner.Visible="on"; app.SmoothingSpinnerLabel.Visible="on";
                    app.ShowSpectralBandsCheckBox.Visible="on";
                    app.CropImagesCheckBox.Visible="on";
                    app.FigureTitle.Visible="on"; app.FigureTitleLabel.Visible="on";
                    app.ApplyFitButton.Visible="off";
                    app.ApplyFitAllButton.Visible="off";
                    app.BiFitTable.Visible="off";
                    app.TriFitTable.Visible="off";
            end
        end

        % Button pushed function: ApplyFitButton
        function ApplyFitButtonPushed(app, event)
            index = app.IndexSlider.Value;
            if app.ShowFitOptionsCheckBox.Value == true
                app.applyOpt(index);
            end
            if app.CropImagesCheckBox.Value == true
                app.imageYs{index} = [app.YCrop.Lim1 app.YCrop.Lim2];
                app.cropDecay(index);
            end
        end

        % Button pushed function: ApplyFitAllButton
        function ApplyFitAllButtonPushed(app, event)
            if app.ShowFitOptionsCheckBox.Value == true
                app.applyAllOpt();
            end
            if app.CropImagesCheckBox.Value == true
                for i = 1:size(app.imageYs,2)
                    app.imageYs{i} = [app.YCrop.Lim1 app.YCrop.Lim2];
                    app.cropDecay(i);
                end
            end
        end

        % Value changed function: CropImagesCheckBox
        function CropImagesCheckBoxValueChanged(app, event)
            value = app.CropImagesCheckBox.Value;
            switch value
                case true
                    app.NormalisationPointSpinner.Visible="off"; app.NormalisationPointSpinnerLabel.Visible="off";
                    app.ZeroPointSpinner.Visible="off"; app.ZeroPointSpinnerLabel.Visible="off";
                    app.SmoothingSpinner.Visible="off"; app.SmoothingSpinnerLabel.Visible="off";
                    app.ShowSpectralBandsCheckBox.Visible="off";
                    app.ShowFitOptionsCheckBox.Visible="off";
                    app.YCrop.Visible="on";
                    app.ApplyFitButton.Visible="on";
                    app.ApplyFitAllButton.Visible="on";
                case false
                    app.NormalisationPointSpinner.Visible="on"; app.NormalisationPointSpinnerLabel.Visible="on";
                    app.ZeroPointSpinner.Visible="on"; app.ZeroPointSpinnerLabel.Visible="on";
                    app.SmoothingSpinner.Visible="on"; app.SmoothingSpinnerLabel.Visible="on";
                    app.ShowSpectralBandsCheckBox.Visible="on";
                    app.ShowFitOptionsCheckBox.Visible="on";
                    app.YCrop.Visible="off";
                    app.ApplyFitButton.Visible="off";
                    app.ApplyFitAllButton.Visible="off";
            end
        end

        % Value changed function: NormalisationPointSpinner
        function NormalisationPointSpinnerValueChanged(app, event)
            value = app.NormalisationPointSpinner.Value;            
            app.normalisation_peak=value;
        end

        % Value changed function: ZeroPointSpinner
        function ZeroPointSpinnerValueChanged(app, event)
            value = app.ZeroPointSpinner.Value;
            app.ZERO=value;
        end

        % Value changed function: SmoothingSpinner
        function SmoothingSpinnerValueChanged(app, event)
            value = app.SmoothingSpinner.Value;
            app.smoothing=value;            
        end

        % Value changed function: FigureTitle
        function FigureTitleValueChanged(app, event)
            title = app.FigureTitle.Value;
            index = app.IndexSlider.Value;
            app.Temperature(index) = title;
            app.MainDisplay.Title.String=app.Temperature(index);
        end

        % Value changed function: IndexSlider
        function IndexSliderValueChanged(app, event)
            index = app.IndexSlider.Value;
            [~, minIdx] = min(abs(index - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            index = event.Source.MajorTicks(minIdx);
            switch app.filter
                case 1
                    app.decaySliderChange(index);
                case 2
                    if app.single==1
                        app.updateImage(app.MainDisplay, index, app.norm);
                    end
                otherwise
            end
        end

        % Value changed function: SpectralBand
        function SpectralBandValueChanged(app, event)
            band = app.SpectralBand.Value;
            [~, minIdx] = min(abs(band - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            band = event.Source.MajorTicks(minIdx);
            if app.DisplayType.SelectedObject==app.FittingButton
                app.decayBandChange(band);
            end
            app.CurrentBand.Text = app.Bands_sel(band*2-1)+" nm - "+app.Bands_sel(band*2)+" nm";
        end

        % Selection changed function: DisplayType
        function DisplayTypeSelectionChanged(app, event)
            selectedButton = app.DisplayType.SelectedObject;
            index=app.IndexSlider.Value;
            band = app.SpectralBand.Value;
            switch selectedButton
                case app.HeatMapButton
                    app.TriFitParameters.Visible="off";
                    app.BiFitParameters.Visible="off";
                    app.showHeatMap();
                    app.updateHeatMap(index);
                    app.show_parameters=0;
                    app.moveFittingPlots(0);
                    app.ContextLabel.Visible="on";
                    app.Context2.Visible="on";
                    app.Context2.Text="Manual Scale";
                    app.CropCheckBox.Visible="on";
                    app.NormCheckBox.Visible="on";
                    app.ColorScale.Visible="off";
                    switch app.rel_color
                        case 0
                            app.DisplayContext.Text="Relative Scale";
                            app.ContextLabel.Text="Current Scale: Absolute";
                        case 1
                            app.DisplayContext.Text="Absolute Scale";
                            app.ContextLabel.Text="Current Scale: Relative";
                        otherwise
                    end
                case app.FittingButton
                    showFitting(app, index, band);
                    app.DisplayContext.Text="Show Fitting Parameters";
                    app.ContextLabel.Visible="off";
                    app.Context2.Visible="off";
                    app.CropCheckBox.Visible="off";
                    app.NormCheckBox.Visible="off";
                    app.ColorScale.Visible="off";
                    app.show_parameters=0;
                case app.CWButton
                    app.delayImages(index, app.norm);
                    switch app.norm
                        case 0
                            app.DisplayContext.Text="Normalised";
                            app.ContextLabel.Text="Current Scale: Raw";
                        case 1
                            app.DisplayContext.Text="Raw";
                            app.ContextLabel.Text="Current Scale: Normalised";
                    end
                    app.ContextLabel.Visible="on";
                    app.Context2.Visible="on";
                    app.CropCheckBox.Visible="off";
                    app.NormCheckBox.Visible="off";
            end
        end

        % Button pushed function: DisplayContext
        function DisplayContextPushed(app, event)
            index=app.IndexSlider.Value;
            band=app.SpectralBand.Value;
            switch app.DisplayType.SelectedObject
                case app.HeatMapButton
                    switch app.rel_color
                        case {2,3}
                            app.ColorScale.Visible="off";
                    end
                    app.relativeDisplay(app.rel_color)
                case app.FittingButton
                    switch app.show_parameters
                        case 0
                            app.moveFittingPlots(1);
                            app.DisplayContext.Text="Hide Fitting Parameters";
                            app.fitTable(index, band);
                            app.TriFitParameters.Visible="on";
                            app.BiFitParameters.Visible="on";
                            app.show_parameters=1;
                        case 1
                            app.DisplayContext.Text="Show Fitting Parameters";
                            app.TriFitParameters.Visible="off";
                            app.BiFitParameters.Visible="off";
                            app.moveFittingPlots(0);
                            app.show_parameters=0;
                    end
                case app.CWButton
                    switch app.filter
                        case 1
                            switch app.norm
                                case 0
                                    app.delayImages(index, app.norm);
                                    app.DisplayContext.Text="Raw";
                                    app.ContextLabel.Text="Current Scale: Normalised";
                                    app.norm=1;
                                case 1
                                    app.delayImages(index, app.norm);
                                    app.DisplayContext.Text="Normalised";
                                    app.ContextLabel.Text="Current Scale: Raw Data";
                                    app.norm=0;
                            end
                        case 2
                            switch app.norm
                                case 0
                                    app.DisplayContext.Text="Raw";
                                    app.ContextLabel.Text="Current Scale: Normalised";
                                    app.norm=1;
                                    switch app.single
                                        case 0
                                            app.showAllImages(app.images, app.norm);
                                        case 1
                                            app.updateImage(app.MainDisplay, index, app.norm);
                                    end
                                case 1
                                    app.DisplayContext.Text="Normalised";
                                    app.ContextLabel.Text="Current Scale: Raw Data";
                                    app.norm=0;
                                    switch app.single
                                        case 0
                                            app.showAllImages(app.images, app.norm);
                                        case 1
                                            app.updateImage(app.MainDisplay, index, app.norm);
                                    end
                            end
                    end
            end
        end

        % Button pushed function: Context2
        function Context2ButtonPushed(app, event)
            switch app.filter
                case 1
                    switch app.DisplayType.SelectedObject
                        case app.HeatMapButton                                    
                            app.ColorScale.Visible="on";
                            switch app.rel_color
                                case 1
                                    app.DisplayContext.Text="Relative Scale";
                                    app.ContextLabel.Text="Current Scale: Manual";
                                    clim(app.MainDisplay, app.colorBarLims);
                                    app.rel_color=2;
                                case 0
                                    app.DisplayContext.Text="Absolute Scale";
                                    app.ContextLabel.Text="Current Scale: Manual";
                                    clim(app.MainDisplay, app.colorBarLims);
                                    app.rel_color=3;
                            end
                        % CWButton in LT mode allows for viewing all delays
                        % in a decay as a single CW or for summing selected
                        % decays only
                        case app.CWButton
                            switch app.single
                                % Switches display to selected decays
                                case 0
                                    app.Context2.Text="All Delays";
                                    
                                    app.single=1;
                                % Switches display to all decays
                                case 1
                                    app.Context2.Text="Delay Crop";
                                    app.single=0;
                            end
                    end
                case 2
                    switch app.single
                        case 0
                            app.Context2.Text="All Images";
                            app.updateImage(app.MainDisplay, app.IndexSlider.Value, app.norm);
                            legend(app.MainDisplay,'off');
                            app.single=1;
                        case 1
                            app.Context2.Text="Single Images";
                            app.showAllImages(app.images, app.norm);
                            app.single=0;
                    end
            end
        end

        % Callback function: ColorScale
        function ColorScaleValueChanged(app, event)
            limit1 = app.ColorScale.Lim1;
            limit2 = app.ColorScale.Lim2;
            app.colorBarLims=[limit1 limit2];
            clim(app.MainDisplay, app.colorBarLims);
        end

        % Value changed function: CropCheckBox
        function CropCheckBoxValueChanged(app, event)
            value = app.CropCheckBox.Value;
            switch value
                case true
                    app.XCrop.Visible="on";
                    app.delayCrop.Visible="on";
                    app.updateHeatMap(app.IndexSlider.Value);
                case false
                    app.XCrop.Visible="off";
                    app.delayCrop.Visible="off";
                    app.updateHeatMap(app.IndexSlider.Value);
            end
        end

        % Value changed function: NormCheckBox
        function NormCheckBoxValueChanged(app, event)
            value = app.NormCheckBox.Value;
            app.updateHeatMap(app.IndexSlider.Value);
            switch value
                case true
                    clim(app.MainDisplay, "auto");
                case false
                    app.relativeDisplay(app.rel_color);
            end
        end

        % Callback function: XCrop
        function XCropValueChanged(app, event)
            limit1 = app.XCrop.Lim1;
            limit2 = app.XCrop.Lim2;
            [~,idx1]=min(abs(app.bin8.WL_bin8 - limit1));
            [~,idx2]=min(abs(app.bin8.WL_bin8 - limit2));
            app.xLim = [app.bin8.WL_bin8(idx1) app.bin8.WL_bin8(idx2)];
            app.updateHeatMap(app.IndexSlider.Value);
        end

        % Callback function: delayCrop
        function delayCropValueChanged(app, event)
            limit1 = app.delayCrop.Lim1;
            limit2 = app.delayCrop.Lim2;
            app.yLim = [limit1 limit2];
            app.updateHeatMap(app.IndexSlider.Value);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if size(app.FileName,2)==1
                return
            end
            key = event.Key;
            switch key
                case 'rightarrow'
                    if app.IndexSlider.Value==app.IndexSlider.Limits(2)
                        return
                    end
                    app.IndexSlider.Value=app.IndexSlider.Value+1;
                    if app.filter==1
                        app.decaySliderChange(app.IndexSlider.Value);
                    elseif app.filter==2
                        if app.single==1
                            app.updateImage(app.MainDisplay, app.IndexSlider.Value, app.norm);
                        end
                    end
                case 'leftarrow'
                    if app.IndexSlider.Value==app.IndexSlider.Limits(1)
                        return
                    end
                    app.IndexSlider.Value=app.IndexSlider.Value-1;
                    if app.filter==1
                        app.decaySliderChange(app.IndexSlider.Value);
                    elseif app.filter==2
                        if app.single==1
                            app.updateImage(app.MainDisplay, app.IndexSlider.Value, app.norm);
                        end
                    end
                case 'uparrow'
                    if app.filter==2
                        return
                    end
                    if app.SpectralBand.Value==app.SpectralBand.Limits(2)
                        return
                    end
                    app.SpectralBand.Value=app.SpectralBand.Value+1;
                    app.CurrentBand.Text = app.Bands_sel(app.SpectralBand.Value*2-1)+" nm - "+app.Bands_sel(app.SpectralBand.Value*2)+" nm";
                    if app.filter==1 && app.DisplayType.SelectedObject==app.FittingButton
                        app.decayBandChange(app.SpectralBand.Value);
                    end
                case 'downarrow'
                    if app.filter==2
                        return
                    end
                    if app.SpectralBand.Value==app.SpectralBand.Limits(1)
                        return
                    end
                    app.SpectralBand.Value=app.SpectralBand.Value-1;
                    app.CurrentBand.Text = app.Bands_sel(app.SpectralBand.Value*2-1)+" nm - "+app.Bands_sel(app.SpectralBand.Value*2)+" nm";
                    if app.filter==1 && app.DisplayType.SelectedObject==app.FittingButton
                        app.decayBandChange(app.SpectralBand.Value);
                    end
                otherwise
                    return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             try
%                 delete(app.pool)
%             catch
%                 return
%             end
            delete(app)            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {420, 420, 420};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {420, 420};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {171, '1x', 203};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.LoadFilesMenu = uimenu(app.OptionsMenu);
            app.LoadFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFilesMenuSelected, true);
            app.LoadFilesMenu.Text = 'Load Files';
            app.PrintExcel = uimenu(app.OptionsMenu);
            app.PrintExcel.MenuSelectedFcn = createCallbackFcn(app, @PrintExcelMenuSelected, true);
            app.PrintExcel.Text = 'Export Excel File';
            app.ExportHeatMapMenu = uimenu(app.OptionsMenu);
            app.ExportHeatMapMenu.Text = 'Export Heat Map';
            app.ExportSingleHeatMap = uimenu(app.ExportHeatMapMenu);
            app.ExportSingleHeatMap.MenuSelectedFcn = createCallbackFcn(app, @ExportSingleHeatMapMenuSelected, true);
            app.ExportSingleHeatMap.Text = 'Single';
            app.ExportAllHeatMaps = uimenu(app.ExportHeatMapMenu);
            app.ExportAllHeatMaps.MenuSelectedFcn = createCallbackFcn(app, @ExportAllHeatMapsMenuSelected, true);
            app.ExportAllHeatMaps.Text = 'All';
            app.FlimFitButton = uimenu(app.UIFigure);
            app.FlimFitButton.MenuSelectedFcn = createCallbackFcn(app, @FlimFitButtonMenuSelected, true);
            app.FlimFitButton.Visible = 'off';
            app.FlimFitButton.Text = 'FlimFit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadedFilesLabel = uilabel(app.LeftPanel);
            app.LoadedFilesLabel.Text = ' Loaded Files:';
            app.ReprocessButton = uibutton(app.LeftPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Visible = 'off';
            app.ReprocessButton.Text = 'Reprocess';
            app.SmoothingSpinner = uispinner(app.LeftPanel);
            app.SmoothingSpinner.Limits = [0 50];
            app.SmoothingSpinner.ValueChangedFcn = createCallbackFcn(app, @SmoothingSpinnerValueChanged, true);
            app.SmoothingSpinner.Visible = 'off';
            app.SmoothingSpinner.Value = 3;
            app.NormalisationPointSpinnerLabel = uilabel(app.LeftPanel);
            app.NormalisationPointSpinnerLabel.Visible = 'off';
            app.NormalisationPointSpinnerLabel.Text = 'Normalisation Point';
            app.ZeroPointSpinnerLabel = uilabel(app.LeftPanel);
            app.ZeroPointSpinnerLabel.Visible = 'off';
            app.ZeroPointSpinnerLabel.Text = 'Zero Point';
            app.ZeroPointSpinner = uispinner(app.LeftPanel);
            app.ZeroPointSpinner.Limits = [0 Inf];
            app.ZeroPointSpinner.ValueChangedFcn = createCallbackFcn(app, @ZeroPointSpinnerValueChanged, true);
            app.ZeroPointSpinner.Visible = 'off';
            app.ZeroPointSpinner.Value = 43;
            app.NormalisationPointSpinner = uispinner(app.LeftPanel);
            app.NormalisationPointSpinner.Limits = [0 50];
            app.NormalisationPointSpinner.ValueChangedFcn = createCallbackFcn(app, @NormalisationPointSpinnerValueChanged, true);
            app.NormalisationPointSpinner.Visible = 'off';
            app.NormalisationPointSpinner.Value = 3;
            app.SmoothingSpinnerLabel = uilabel(app.LeftPanel);
            app.SmoothingSpinnerLabel.Visible = 'off';
            app.SmoothingSpinnerLabel.Text = 'Smoothing';
            app.ShowSpectralBandsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowSpectralBandsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSpectralBandsCheckBoxValueChanged, true);
            app.ShowSpectralBandsCheckBox.Visible = 'off';
            app.ShowSpectralBandsCheckBox.Text = 'Show Spectral Bands';
            app.SpectralBandsSpinnerLabel = uilabel(app.LeftPanel);
            app.SpectralBandsSpinnerLabel.Visible = 'off';
            app.SpectralBandsSpinnerLabel.Text = '# Spectral Bands';
            app.SpectralBandsSpinner = uispinner(app.LeftPanel);
            app.SpectralBandsSpinner.Limits = [1 7];
            app.SpectralBandsSpinner.ValueChangedFcn = createCallbackFcn(app, @SpectralBandsSpinnerValueChanged, true);
            app.SpectralBandsSpinner.Visible = 'off';
            app.SpectralBandsSpinner.Value = 3;
            app.TriFitTable = uitable(app.LeftPanel);
            app.TriFitTable.ColumnName = {'Lower'; 'Start'; 'Upper'};
            app.TriFitTable.RowName = {'A1'; 'A2'; 'A3'; 'offset'; 'tau1'; 'tau2'; 'tau3'};
            app.TriFitTable.ColumnEditable = true;
            app.TriFitTable.Multiselect = 'off';
            app.TriFitTable.Visible = 'off';
            app.BiFitTable = uitable(app.LeftPanel);
            app.BiFitTable.ColumnName = {'Lower'; 'Start'; 'Upper'};
            app.BiFitTable.RowName = {'A1'; 'A2'; 'offset'; 'tau1'; 'tau2'};
            app.BiFitTable.ColumnEditable = true;
            app.BiFitTable.Multiselect = 'off';
            app.BiFitTable.Visible = 'off';
            app.ApplyFitButton = uibutton(app.LeftPanel, 'push');
            app.ApplyFitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFitButtonPushed, true);
            app.ApplyFitButton.Visible = 'off';
            app.ApplyFitButton.Text = 'Apply';
            app.ApplyFitAllButton = uibutton(app.LeftPanel, 'push');
            app.ApplyFitAllButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFitAllButtonPushed, true);
            app.ApplyFitAllButton.Visible = 'off';
            app.ApplyFitAllButton.Text = 'Apply to All';
            app.YCrop = RangeInput(app.LeftPanel);
            app.YCrop.Name = "Y Index";
            app.YCrop.Visible = 'off';
            app.CropImagesCheckBox = uicheckbox(app.LeftPanel);
            app.CropImagesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropImagesCheckBoxValueChanged, true);
            app.CropImagesCheckBox.Visible = 'off';
            app.CropImagesCheckBox.Text = 'Crop Images';
            app.ShowFitOptionsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowFitOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFitOptionsCheckBoxValueChanged, true);
            app.ShowFitOptionsCheckBox.Visible = 'off';
            app.ShowFitOptionsCheckBox.Text = 'Show Fit Options';
            app.FigureTitleLabel = uilabel(app.LeftPanel);
            app.FigureTitleLabel.Visible = 'off';
            app.FigureTitleLabel.Text = 'Title';
            app.FigureTitle = uieditfield(app.LeftPanel, 'text');
            app.FigureTitle.ValueChangedFcn = createCallbackFcn(app, @FigureTitleValueChanged, true);
            app.FigureTitle.Visible = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.Subplot4 = uiaxes(app.GridLayout2);
            xlabel(app.Subplot4, 'Time (ns)')
            ylabel(app.Subplot4, 'Error')
            colormap(app.Subplot4, 'parula')
            app.Subplot4.Visible = 'off';
            app.Subplot3 = uiaxes(app.GridLayout2);
            xlabel(app.Subplot3, 'Time (ns)')
            ylabel(app.Subplot3, 'Error')
            colormap(app.Subplot3, 'parula')
            app.Subplot3.Visible = 'off';
            app.Subplot2 = uiaxes(app.GridLayout2);
            title(app.Subplot2, 'Tri-exponential')
            xlabel(app.Subplot2, 'Time (ns)')
            ylabel(app.Subplot2, 'Intensity')
            colormap(app.Subplot2, 'parula')
            app.Subplot2.Visible = 'off';
            app.Subplot1 = uiaxes(app.GridLayout2);
            title(app.Subplot1, 'Bi-exponential')
            xlabel(app.Subplot1, 'Time (ns)')
            ylabel(app.Subplot1, 'Intensity')
            colormap(app.Subplot1, 'parula')
            app.Subplot1.Visible = 'off';
            app.MainDisplay = uiaxes(app.GridLayout2);
            title(app.MainDisplay, 'Title')
            xlabel(app.MainDisplay, 'Wavelength (nm)')
            ylabel(app.MainDisplay, 'Delay (ns)')
            zlabel(app.MainDisplay, 'Intensity')
            app.MainDisplay.XLim = [370 700];
            colormap(app.MainDisplay, 'parula')
            app.MainDisplay.Visible = 'off';
            app.TriFitParameters = uitable(app.GridLayout2);
            app.TriFitParameters.ColumnName = {'A1'; 'A2'; 'A3'; 'tau1'; 'tau2'; 'tau3'; 'offset'};
            app.TriFitParameters.RowName = {};
            app.TriFitParameters.Multiselect = 'off';
            app.TriFitParameters.Visible = 'off';
            app.BiFitParameters = uitable(app.GridLayout2);
            app.BiFitParameters.ColumnName = {'A1'; 'A2'; 'tau1'; 'tau2'; 'offset'};
            app.BiFitParameters.RowName = {};
            app.BiFitParameters.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.IndexSliderLabel = uilabel(app.RightPanel);
            app.IndexSliderLabel.Visible = 'off';
            app.IndexSliderLabel.Text = 'Index';
            app.IndexSlider = uislider(app.RightPanel);
            app.IndexSlider.Limits = [1 2];
            app.IndexSlider.MajorTicks = [];
            app.IndexSlider.ValueChangedFcn = createCallbackFcn(app, @IndexSliderValueChanged, true);
            app.IndexSlider.MinorTicks = [1 1.05 1.1 1.15 1.2 1.25 1.3 1.35 1.4 1.45 1.5 1.55 1.6 1.65 1.7 1.75 1.8 1.85 1.9 1.95 2];
            app.IndexSlider.Visible = 'off';
            app.IndexSlider.Value = 1;
            app.SpectralBandLabel = uilabel(app.RightPanel);
            app.SpectralBandLabel.Visible = 'off';
            app.SpectralBandLabel.Text = 'Spectral Band';
            app.SpectralBand = uislider(app.RightPanel);
            app.SpectralBand.Limits = [1 3];
            app.SpectralBand.MajorTicks = [];
            app.SpectralBand.ValueChangedFcn = createCallbackFcn(app, @SpectralBandValueChanged, true);
            app.SpectralBand.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.SpectralBand.Visible = 'off';
            app.SpectralBand.Value = 1;
            app.DisplayType = uibuttongroup(app.RightPanel);
            app.DisplayType.SelectionChangedFcn = createCallbackFcn(app, @DisplayTypeSelectionChanged, true);
            app.DisplayType.Visible = 'off';
            app.CWButton = uitogglebutton(app.DisplayType);
            app.CWButton.Text = 'CW';
            app.FittingButton = uitogglebutton(app.DisplayType);
            app.FittingButton.Text = 'Fitting';
            app.HeatMapButton = uitogglebutton(app.DisplayType);
            app.HeatMapButton.Text = 'Heat Map';
            app.HeatMapButton.Value = true;
            app.ColorScale = RangeInput(app.RightPanel);
            app.ColorScale.Lim1 = 0;
            app.ColorScale.Lim2 = 10;
            app.ColorScale.Name = "Limit";
            app.ColorScale.ValueChangedFcn = createCallbackFcn(app, @ColorScaleValueChanged, true);
            app.ColorScale.Visible = 'off';
            app.Context2 = uibutton(app.RightPanel, 'push');
            app.Context2.ButtonPushedFcn = createCallbackFcn(app, @Context2ButtonPushed, true);
            app.Context2.Visible = 'off';
            app.Context2.Text = 'Context';
            app.DisplayContext = uibutton(app.RightPanel, 'push');
            app.DisplayContext.ButtonPushedFcn = createCallbackFcn(app, @DisplayContextPushed, true);
            app.DisplayContext.Visible = 'off';
            app.DisplayContext.Text = 'Context';
            app.ContextLabel = uilabel(app.RightPanel);
            app.ContextLabel.Visible = 'off';
            app.ContextLabel.Text = '';
            app.CurrentBand = uilabel(app.RightPanel);
            app.CurrentBand.Visible = 'off';
            app.CurrentBand.Text = '';
            app.delayCrop = RangeInput(app.RightPanel);
            app.delayCrop.Name = "Delays";
            app.delayCrop.ValueChangedFcn = createCallbackFcn(app, @delayCropValueChanged, true);
            app.delayCrop.Visible = 'off';
            app.XCrop = RangeInput(app.RightPanel);
            app.XCrop.Lim1 = 329;
            app.XCrop.Lim2 = 756;
            app.XCrop.Name = "Wavelength";
            app.XCrop.ValueChangedFcn = createCallbackFcn(app, @XCropValueChanged, true);
            app.XCrop.Visible = 'off';
            app.CropCheckBox = uicheckbox(app.RightPanel);
            app.CropCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropCheckBoxValueChanged, true);
            app.CropCheckBox.Visible = 'off';
            app.CropCheckBox.Text = 'Crop';
            app.NormCheckBox = uicheckbox(app.RightPanel);
            app.NormCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormCheckBoxValueChanged, true);
            app.NormCheckBox.Visible = 'off';
            app.NormCheckBox.Text = 'Norm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FluorescenceProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end