classdef RangeInput < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        RangeLabel  matlab.ui.control.Label
        RangeEnd    matlab.ui.control.NumericEditField
        Label       matlab.ui.control.Label
        RangeStart  matlab.ui.control.NumericEditField
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        FontSize double = 12;
        Lim1 double = 1;
        Lim2 double = 2;
        Name string = 'Range';
        WordWrap logical = true;
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RangeStart
        function RangeStartValueChanged(comp, event)
            value = comp.RangeStart.Value;
            comp.Lim1 = value;
            notify(comp,"ValueChanged");
        end

        % Value changed function: RangeEnd
        function RangeEndValueChanged(comp, event)
            value = comp.RangeEnd.Value;
            comp.Lim2 = value;
            notify(comp,"ValueChanged");
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
           comp.RangeStart.Value = comp.Lim1;
           comp.RangeEnd.Value = comp.Lim2;
           comp.RangeLabel.Text = comp.Name;
           comp.RangeLabel.WordWrap = comp.WordWrap;
           comp.RangeLabel.FontSize = comp.FontSize;
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 166 30];
            comp.RangeStart = uieditfield(comp, 'numeric');
            comp.RangeStart.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RangeStartValueChanged, true);
            comp.RangeStart.Value = 1;
            comp.Label = uilabel(comp);
            comp.Label.Enable = 'off';
            comp.Label.Text = '-';
            comp.RangeEnd = uieditfield(comp, 'numeric');
            comp.RangeEnd.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @RangeEndValueChanged, true);
            comp.RangeEnd.Value = 2;
            comp.RangeLabel = uilabel(comp);
            comp.RangeLabel.Text = 'Range';
        end
    end
end